classdef HFMaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HedgeFundTestingUIFigure        matlab.ui.Figure
        HFNameLabel                     matlab.ui.control.Label
        HFNameDropDown                  matlab.ui.control.DropDown
        BenchmarkDropDownLabel          matlab.ui.control.Label
        BenchmarkDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        RunTestButton                   matlab.ui.control.Button
        HedgeFundTimingAbilityTestingLabel  matlab.ui.control.Label
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        Button                          matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        OutlierThresholdEditFieldLabel  matlab.ui.control.Label
        OutlierThresholdEditField       matlab.ui.control.NumericEditField
        RunBatchButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        hHeader;
        sHeader;
        fHeader;
        factors;
        rtns;
        dbi;
        cacheStartDate;
        cacheEndDate;
    end
    
    properties (Access = private, Constant)
        betaTimingType = 'Dual Beta';
        methodTypeKeys = ["Dual Beta" "Squared Return" "Floored Return" "Absolute Return" "Non-Parametric"];
        methodTypeFncs = {@dualBetaTest, @squaredReturnTest, @flooredReturnTest, @absoluteReturnTest, @nonparametricTimingTest};
        methodTypes = containers.Map(HFMaster.methodTypeKeys, HFMaster.methodTypeFncs);
    
    end

    methods (Access = private)
        
        function results = dbLoad(app, endDate, startDate)
            cfg.startDt = datestr(startDate,'yyyy-mm-dd');
            cfg.endDt = datestr(endDate,'yyyy-mm-dd');
            results = loadRapcDb(app.dbi, cfg);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            PubEqPath.addLibPath('_util', '_data', '_platform', '_database');
            
            startDate = app.StartDateDatePicker.Value;
            endDate = app.EndDateDatePicker.Value;
            app.cacheStartDate = startDate;
            app.cacheEndDate = endDate;
            
            cfg = getRapcConfig;
            app.dbi = Env.newPubEqCoreDb();
            outStruct = app.dbLoad(endDate, startDate);
            outStruct = preProcRapcInput(outStruct, cfg);
            unpack(outStruct);
            
            app.HFNameDropDown.Items = sort(hHeader);
            app.HFNameDropDown.Value = hHeader(26);
            
            app.BenchmarkDropDown.Items = fHeader;
            app.BenchmarkDropDown.Value = fHeader(1);
            
            app.MethodDropDown.Items = keys(app.methodTypes);
            app.MethodDropDown.Value = app.methodTypeKeys(1);
            
            app.fHeader = fHeader;
            app.hHeader = hHeader;
            app.sHeader = style.funds;
            app.factors = factors;
            app.rtns = rtns;
        end

        % Button pushed function: RunTestButton
        function RunTestButtonPushed(app, event)
        
            startDate = app.StartDateDatePicker.Value;
            endDate = app.EndDateDatePicker.Value;
            if (app.cacheStartDate ~= startDate || app.cacheEndDate ~= endDate)
                cfg = getRapcConfig;
                outStruct = app.dbLoad(endDate, startDate);
                outStruct = preProcRapcInput(outStruct, cfg);
                unpack(outStruct);
                app.fHeader = fHeader;
                app.hHeader = hHeader;
                app.factors = factors;
                app.rtns = rtns;
                app.cacheStartDate = startDate;
                app.cacheEndDate = endDate;
            end
            
            outlierThreshold = app.OutlierThresholdEditField.Value;
            
            fIndex = find(ismember(app.fHeader, app.BenchmarkDropDown.Value));
            hIndex = find(ismember(app.hHeader, app.HFNameDropDown.Value));
            rfIndex = find(ismember(app.fHeader, 'USD LIBOR 3M'));
            
            msciRtns = app.factors(:, fIndex);
            bwRtns = app.rtns(:, hIndex);
            rfRtns = app.factors(:, rfIndex);
          
            %clean NaN
            nonNaNIndex = ~isnan(bwRtns);
            msciRtns = msciRtns(nonNaNIndex);
            bwRtns = bwRtns(nonNaNIndex);
            rfRtns = rfRtns(nonNaNIndex);
            
            [t1, ~] = size(bwRtns);
            [t2, ~] = size(msciRtns);

            if (t1 ~= t2)
                uialert(app.HedgeFundTestingUIFigure, 'Size of HF returns does not match size of benchmark returns.', 'Error', 'Icon','error');
            else
                fnc = app.methodTypes(app.MethodDropDown.Value);
                fnc(bwRtns, msciRtns, rfRtns, app.hHeader(hIndex), app.fHeader(fIndex), outlierThreshold);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            messageStr = '';
            if app.MethodDropDown.Value == app.betaTimingType
                messageStr = 'Performs regression on positive and negative partitions of given factor and tests the hypothesis if the beta coefficient from positive partition has higher value than beta from negative partition.';
            end
            
            uialert(app.HedgeFundTestingUIFigure, messageStr, 'Details', 'Icon','info');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq
        end

        % Button pushed function: RunBatchButton
        function RunBatchButtonPushed(app, event)
            startDate = app.StartDateDatePicker.Value;
            endDate = app.EndDateDatePicker.Value;
            if (app.cacheStartDate ~= startDate || app.cacheEndDate ~= endDate)
                cfg = getRapcConfig;
                outStruct = app.dbLoad(endDate, startDate);
                outStruct = preProcRapcInput(outStruct, cfg);
                unpack(outStruct);
                app.fHeader = fHeader;
                app.hHeader = hHeader;
                app.factors = factors;
                app.rtns = rtns;
                app.cacheStartDate = startDate;
                app.cacheEndDate = endDate;
            end
            
            outlierThreshold = app.OutlierThresholdEditField.Value;
            
            fIndex = find(ismember(app.fHeader, app.BenchmarkDropDown.Value));
            rfIndex = find(ismember(app.fHeader, 'USD LIBOR 3M'));
            
            msciRtns = app.factors(:, fIndex);
            rfRtns = app.factors(:, rfIndex);
            
            [t1, hTotal] = size(app.rtns);
            [t2, ~] = size(msciRtns);

            if (t1 ~= t2)
                uialert(app.HedgeFundTestingUIFigure, 'Size of HF returns does not match size of benchmark returns.', 'Error', 'Icon','error');
            else
                res = [];
                hIndex = 1;
                
                d = uiprogressdlg(app.HedgeFundTestingUIFigure,'Title','Running Batch',...
                    'Message','Running Batch...');
                
                for bwRtns = app.rtns
                    
                    msciRtns = app.factors(:, fIndex);
                    rfRtns = app.factors(:, rfIndex);
                    
                    %clean NaN
                    nonNaNIndex = ~isnan(bwRtns);
                    msciRtnsi = msciRtns(nonNaNIndex);
                    bwRtnsi = bwRtns(nonNaNIndex);
                    rfRtnsi = rfRtns(nonNaNIndex);
                    
                    ret = [];
                    d.Message = sprintf('Running tests for: %s', char(app.hHeader(hIndex)));
                    d.Value = hIndex / hTotal;
                    for testKey = app.methodTypeKeys
                        fnc = app.methodTypes(testKey);
                        ret = [ret fnc(bwRtnsi, msciRtnsi, rfRtnsi, app.hHeader(hIndex), app.fHeader(fIndex), outlierThreshold, false)];
                    end
                    res = [res; ret];
                    hIndex = hIndex + 1;
                end
                
                close(d)
                
                resHeader = {'Hedge Fund Name', 'Strategy Type',...
                             strcat('Dual Beta: ',{'Alpha1', 'Beta1', 'Alpha2', 'Beta2', 'H0: pval'}), ...
                             strcat('Squared Returns: ',{'Alpha', 'Beta1', 'Beta2', 'Alpha pval', 'Beta1 pval', 'Beta2 pval'}), ...
                             strcat('Floored Returns: ',{'Alpha', 'Beta1', 'Beta2', 'Alpha pval', 'Beta1 pval', 'Beta2 pval'}), ...
                             strcat('Absolute Returns: ',{'Alpha', 'Beta1', 'Beta2', 'Alpha pval', 'Beta1 pval', 'Beta2 pval'}), ...
                             strcat('Non-Parametric: ',{'Theta', 'SD', 'pval'})};
                resHeader = horzcat(resHeader{:});
                res = num2cell(res);
                res = [app.hHeader' app.sHeader' res];
                res = [resHeader; res];
                
                outDataFile = fullfile(PubEqPath.localDataPath(), 'RAPC', 'output','batchTimingResults.csv');
                
                writecell(res, outDataFile);
                
                msgbox("Test results have been written into " + outDataFile);
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HedgeFundTestingUIFigure = uifigure('Visible', 'off');
            app.HedgeFundTestingUIFigure.Name = 'Hedge Fund Testing';
            app.HFNameLabel = uilabel(app.HedgeFundTestingUIFigure);
            app.HFNameLabel.Text = 'HF Name';
            app.HFNameDropDown = uidropdown(app.HedgeFundTestingUIFigure);
            app.BenchmarkDropDownLabel = uilabel(app.HedgeFundTestingUIFigure);
            app.BenchmarkDropDownLabel.Text = 'Benchmark';
            app.BenchmarkDropDown = uidropdown(app.HedgeFundTestingUIFigure);
            app.MethodDropDownLabel = uilabel(app.HedgeFundTestingUIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.HedgeFundTestingUIFigure);
            app.MethodDropDown.Items = {'Test Beta Timing'};
            app.MethodDropDown.Value = 'Test Beta Timing';
            app.RunTestButton = uibutton(app.HedgeFundTestingUIFigure, 'push');
            app.RunTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunTestButtonPushed, true);
            app.RunTestButton.Text = 'Run Test';
            app.HedgeFundTimingAbilityTestingLabel = uilabel(app.HedgeFundTestingUIFigure);
            app.HedgeFundTimingAbilityTestingLabel.Text = 'Hedge Fund Timing Ability Testing';
            app.StartDateDatePickerLabel = uilabel(app.HedgeFundTestingUIFigure);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.HedgeFundTestingUIFigure);
            app.StartDateDatePicker.Value = datetime([2012 7 31]);
            app.EndDateDatePickerLabel = uilabel(app.HedgeFundTestingUIFigure);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.HedgeFundTestingUIFigure);
            app.EndDateDatePicker.Value = datetime([2019 2 28]);
            app.Button = uibutton(app.HedgeFundTestingUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.CancelButton = uibutton(app.HedgeFundTestingUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OutlierThresholdEditFieldLabel = uilabel(app.HedgeFundTestingUIFigure);
            app.OutlierThresholdEditFieldLabel.Text = 'Outlier Threshold';
            app.OutlierThresholdEditField = uieditfield(app.HedgeFundTestingUIFigure, 'numeric');
            app.OutlierThresholdEditField.Value = 100;
            app.RunBatchButton = uibutton(app.HedgeFundTestingUIFigure, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchButtonPushed, true);
            app.RunBatchButton.Text = 'Run Batch';
            % Show the figure after all components are created
            app.HedgeFundTestingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HFMaster

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HedgeFundTestingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HedgeFundTestingUIFigure)
        end
    end
end